classdef test_app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        SMWBBControlTab             matlab.ui.container.Tab
        AlignButton                 matlab.ui.control.Button
        AlignmentFilenameEditField  matlab.ui.control.EditField
        AlignmentFilenameEditFieldLabel  matlab.ui.control.Label
        RFPortAlignmentLabel        matlab.ui.control.Label
        SubnetEditField0            matlab.ui.control.NumericEditField
        BBSubnetLabel               matlab.ui.control.Label
        SMWIPAddressEditField       matlab.ui.control.EditField
        SMWIPAddressEditFieldLabel  matlab.ui.control.Label
        BasebandConfigLabel         matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        SubnetEditField2            matlab.ui.control.NumericEditField
        SubnetEditField1            matlab.ui.control.NumericEditField
        BB_Table                    matlab.ui.control.Table
        InitBasebandsButton         matlab.ui.control.Button
        MessageLabel                matlab.ui.control.Label
        Connect_Message             matlab.ui.control.Label
        SMWControlInterfaceLabel    matlab.ui.control.Label
        ConnectButton               matlab.ui.control.Button
        UITable_2                   matlab.ui.control.Table
        ExectutePDWsTab             matlab.ui.container.Tab
        StreamButton_2              matlab.ui.control.Button
        AddRowButton                matlab.ui.control.Button
        PDWTable                    matlab.ui.control.Table
        StatusLabel                 matlab.ui.control.Label
        PDWFilenameEditField        matlab.ui.control.EditField
        PDWFilenameEditFieldLabel   matlab.ui.control.Label
        StreamButton                matlab.ui.control.Button
        RealTimeStreamPDWsLabel     matlab.ui.control.Label
        ProcessButton               matlab.ui.control.Button
        VerifyExcelFileLabel        matlab.ui.control.Label
        ExecuteEmittersTab          matlab.ui.container.Tab
        HILTimeSyncTab_2            matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Property % Description
    end
    
    properties (Access = public)
        bb_streams = {}; % Description
        bb_stream_IPs = {};
        SMWs = {};
        FSWs = {};
        ZNAs = {};
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AddButtonPushed(app, event)
            addr = app.BBIPAddressEditField.Value;
            [len,wid] = size(app.bb_streams);
            app.bb_streams{len+1,1} = len+1;
            app.bb_streams{len+1,2} = app.SMWs{len+1};
            app.bb_streams{len+1,3} = addr;
            app.PDWTable.Data = app.bb_streams;
        end

        % Callback function
        function UITableCellEdit(app, event)
%             indices = event.Indices;
%             newData = event.NewData;
            
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.Connect_Message.Text = "";
            addr = app.SMWIPAddressEditField.Value;
            [len,wid] = size(app.SMWs)
            for i = 1:len
                if(strcmp(app.UITable_2.Data{i,2},addr))
                    app.Connect_Message.Text = "Already registered SMW";
                    return;
                end
            end
            app.SMWs{len+1,1} = tcpclient(addr,5025, "Timeout",75)
            app.SMWs{len+1,2} = parse_options(app.SMWs{len+1,1})
%             app.SMWs{len+1} = addr;
            app.UITable_2.Data{len+1,1} = len+1;
            app.UITable_2.Data{len+1,2} = addr;
            app.UITable_2.Data{len+1,3} = app.SMWs{len+1,2};
            if(app.SMWs{len+1,2} < 3)
                app.UITable_2.Data{len+1,4} = 'Standard - 2 basebands';
                app.SMWs{len+1,3} = 'Standard - 2 basebands';
            elseif(app.SMWs{len+1,2} == 4)
                app.UITable_2.Data{len+1,4} = 'Standard - 2 basebands';
                app.SMWs{len+1,3} = 'Standard - 2 basebands';
                app.UITable_2.ColumnFormat{4} = {'Standard - 2 basebands','Advanced - 4 basebands'};
            elseif(app.SMWs{len+1,2} == 6)
                app.SMWs{len+1,2}
                app.UITable_2.Data{len+1,4} = 'Standard - 2 basebands';
                app.SMWs{len+1,3} = 'Standard - 2 basebands';
                app.UITable_2.ColumnFormat{4} = {'Standard - 2 basebands','Advanced - 6 basebands'};
            end
            
        end

        % Button pushed function: InitBasebandsButton
        function InitBasebandsButtonPushed(app, event)
            subnet = strcat(num2str(app.SubnetEditField0.Value),".",num2str(app.SubnetEditField1.Value),".",num2str(app.SubnetEditField2.Value),".");
            table = assign_bbs(app.SMWs,subnet)
            for i = 1:length(table)
              app.BB_Table.Data{i,1} = table{i}{1}
              app.BB_Table.Data{i,2} = table{i}{2};
              app.BB_Table.Data{i,3} = sprintf('%s',table{i}{3});
              app.BB_Table.Data{i,4} = sprintf('%s',table{i}{4});
              app.bb_stream_IPs{i} = app.BB_Table.Data{i,3};
            end
        end

        % Button pushed function: AddRowButton
        function AddRowButtonPushed(app, event)
            if(isempty(app.PDWTable.Data))
                app.PDWTable.Data = {};
            end
            app.PDWTable.Data(end+1,:) = {0, 1,'PDW','UNMOD', 0, 0, 0, 20, 5, 5,'LIN',1,'N/A','N/A',1,0,0,'N/A','N/A','N/A','N/A'};
            length(app.bb_stream_IPs)
            app.PDWTable.ColumnFormat{2} = cellstr(string(1:length(app.bb_stream_IPs)));
            app.PDWTable.ColumnFormat{3} = {'PDW', 'ARB','TCDW'};
            app.PDWTable.ColumnFormat{4} = {'UNMOD', ['L' ...
                'CHIRP'],'TCHIRP','BARKER'};
            app.PDWTable.ColumnFormat{11} = {'LIN','COS'};
            app.PDWTable.ColumnFormat{12} = {'ON','OFF'};
        end

        % Cell edit callback: UITable_2
        function UITable_2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.SMWs{indices(1),3} = app.UITable_2.Data{indices(1),indices(2)};
        end

        % Button pushed function: AlignButton
        function AlignButtonPushed(app, event)
            align_smw(app.SMWs{1,1},app.AlignmentFilenameEditField.Value);
        end

        % Button pushed function: StreamButton
        function StreamButtonPushed(app, event)
            filename = sprintf("%s.xlsx",app.PDWFilenameEditField.Value);
            send_pdw_streams(filename,1,0, app.bb_stream_IPs, {app.SMWs{1,1}}, [])
        end

        % Button pushed function: StreamButton_2
        function StreamButton_2Pushed(app, event)
            [len,wid] = size(app.PDWTable.Data);
            dat = (app.PDWTable.ColumnName)'
            dat(end+1:len+1,:) = app.PDWTable.Data;
            send_pdw_streams("XXXX.xlsx",1,0, app.bb_stream_IPs, {app.SMWs{1,1}}, dat);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SMWBBControlTab = uitab(app.TabGroup);
            app.SMWBBControlTab.Title = 'SMW/BB Control';
            app.UITable_2 = uitable(app.SMWBBControlTab);
            app.UITable_2.ColumnName = {'SMW Num'; 'IP Address'; 'Baseband Streams'; 'Configuration'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnEditable = [false false false true];
            app.UITable_2.CellEditCallback = createCallbackFcn(app, @UITable_2CellEdit, true);
            app.ConnectButton = uibutton(app.SMWBBControlTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.SMWControlInterfaceLabel = uilabel(app.SMWBBControlTab);
            app.SMWControlInterfaceLabel.Text = 'SMW Control Interface:';
            app.Connect_Message = uilabel(app.SMWBBControlTab);
            app.Connect_Message.Text = '';
            app.MessageLabel = uilabel(app.SMWBBControlTab);
            app.MessageLabel.Text = 'Message:';
            app.InitBasebandsButton = uibutton(app.SMWBBControlTab, 'push');
            app.InitBasebandsButton.ButtonPushedFcn = createCallbackFcn(app, @InitBasebandsButtonPushed, true);
            app.InitBasebandsButton.Text = 'Init Basebands';
            app.BB_Table = uitable(app.SMWBBControlTab);
            app.BB_Table.ColumnName = {'SMW Num'; 'BB_Num'; 'IP Address'; 'RF Path'};
            app.BB_Table.RowName = {};
            app.SubnetEditField1 = uieditfield(app.SMWBBControlTab, 'numeric');
            app.SubnetEditField1.Value = 168;
            app.SubnetEditField2 = uieditfield(app.SMWBBControlTab, 'numeric');
            app.SubnetEditField2.Value = 58;
            app.Label = uilabel(app.SMWBBControlTab);
            app.Label.Text = '.';
            app.Label_2 = uilabel(app.SMWBBControlTab);
            app.Label_2.Text = '.';
            app.Label_3 = uilabel(app.SMWBBControlTab);
            app.Label_3.Text = '.';
            app.BasebandConfigLabel = uilabel(app.SMWBBControlTab);
            app.BasebandConfigLabel.Text = 'Baseband Config:';
            app.SMWIPAddressEditFieldLabel = uilabel(app.SMWBBControlTab);
            app.SMWIPAddressEditFieldLabel.Text = 'SMW IP Address';
            app.SMWIPAddressEditField = uieditfield(app.SMWBBControlTab, 'text');
            app.SMWIPAddressEditField.Value = '192.168.58.25';
            app.BBSubnetLabel = uilabel(app.SMWBBControlTab);
            app.BBSubnetLabel.Text = 'BB Subnet';
            app.SubnetEditField0 = uieditfield(app.SMWBBControlTab, 'numeric');
            app.SubnetEditField0.Value = 192;
            app.RFPortAlignmentLabel = uilabel(app.SMWBBControlTab);
            app.RFPortAlignmentLabel.Text = 'RF Port Alignment:';
            app.AlignmentFilenameEditFieldLabel = uilabel(app.SMWBBControlTab);
            app.AlignmentFilenameEditFieldLabel.Text = 'Alignment Filename';
            app.AlignmentFilenameEditField = uieditfield(app.SMWBBControlTab, 'text');
            app.AlignButton = uibutton(app.SMWBBControlTab, 'push');
            app.AlignButton.ButtonPushedFcn = createCallbackFcn(app, @AlignButtonPushed, true);
            app.AlignButton.Text = 'Align';
            app.ExectutePDWsTab = uitab(app.TabGroup);
            app.ExectutePDWsTab.Title = 'Exectute PDWs';
            app.VerifyExcelFileLabel = uilabel(app.ExectutePDWsTab);
            app.VerifyExcelFileLabel.Text = 'Verify Excel File:';
            app.ProcessButton = uibutton(app.ExectutePDWsTab, 'push');
            app.ProcessButton.Text = 'Process';
            app.RealTimeStreamPDWsLabel = uilabel(app.ExectutePDWsTab);
            app.RealTimeStreamPDWsLabel.Text = 'Real-Time Stream PDWs:';
            app.StreamButton = uibutton(app.ExectutePDWsTab, 'push');
            app.StreamButton.ButtonPushedFcn = createCallbackFcn(app, @StreamButtonPushed, true);
            app.StreamButton.Text = 'Stream';
            app.PDWFilenameEditFieldLabel = uilabel(app.ExectutePDWsTab);
            app.PDWFilenameEditFieldLabel.Text = 'PDW Filename';
            app.PDWFilenameEditField = uieditfield(app.ExectutePDWsTab, 'text');
            app.StatusLabel = uilabel(app.ExectutePDWsTab);
            app.StatusLabel.Text = 'Status:';
            app.PDWTable = uitable(app.ExectutePDWsTab);
            app.PDWTable.ColumnName = {'TOA (s)'; 'BB Num.'; 'Word Type'; 'MOP'; 'Freq Offset (Hz)'; 'Level Offset (dB)'; 'Phase Offset (deg.)'; 'Pulse Width(us)/Arb Seg Idx.'; 'Rise Time (ns)'; 'Fall Time (ns)'; 'Edge Shape'; 'Repetitions'; 'Bandwidth (MHz)'; 'Chip Rate (MHz)'; 'Marker 1'; 'Marker 2'; 'Marker 3'; 'RF Path'; 'Control Command'; 'Frequency (Hz)'; 'Level (dBm)'};
            app.PDWTable.RowName = {};
            app.PDWTable.ColumnEditable = true;
            app.AddRowButton = uibutton(app.ExectutePDWsTab, 'push');
            app.AddRowButton.ButtonPushedFcn = createCallbackFcn(app, @AddRowButtonPushed, true);
            app.AddRowButton.Text = 'Add Row';
            app.StreamButton_2 = uibutton(app.ExectutePDWsTab, 'push');
            app.StreamButton_2.ButtonPushedFcn = createCallbackFcn(app, @StreamButton_2Pushed, true);
            app.StreamButton_2.Text = 'Stream';
            app.ExecuteEmittersTab = uitab(app.TabGroup);
            app.ExecuteEmittersTab.Title = 'Execute Emitters';
            app.HILTimeSyncTab_2 = uitab(app.TabGroup);
            app.HILTimeSyncTab_2.Title = 'HIL Time Sync';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test_app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end